classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        UnblindMenu                   matlab.ui.container.Menu
        BlindMenu                     matlab.ui.container.Menu
        DEVICEPanel                   matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        CONDITIONDropDown             matlab.ui.control.DropDown
        CONDITIONLabel                matlab.ui.control.Label
        TYPEDropDown                  matlab.ui.control.DropDown
        TYPEDropDownLabel             matlab.ui.control.Label
        DEVICEDropDown                matlab.ui.control.DropDown
        MODELDropDownLabel            matlab.ui.control.Label
        PROTOCOLTIMEPanel             matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        PROTOCOLAvgDescLabel          matlab.ui.control.Label
        PROTOCOLAvgLabel              matlab.ui.control.Label
        PROTOCOLMaxDescLabel          matlab.ui.control.Label
        PROTOCOLMaxLabel              matlab.ui.control.Label
        IDPanel                       matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        STUDYEditField                matlab.ui.control.EditField
        STUDYEditFieldLabel           matlab.ui.control.Label
        RUNEditField                  matlab.ui.control.NumericEditField
        RUNEditFieldLabel             matlab.ui.control.Label
        SUBJECTEditField              matlab.ui.control.NumericEditField
        SUBJECTEditFieldLabel         matlab.ui.control.Label
        EPOCHSPanel                   matlab.ui.container.Panel
        RANDOMIZEButton               matlab.ui.control.Button
        COLORButton                   matlab.ui.control.Button
        MARKERDelButton               matlab.ui.control.Button
        MARKERAddButton               matlab.ui.control.Button
        MARKERTable                   matlab.ui.control.Table
        SESSIONINFOPanel              matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        SESSIONSTATUSLabel            matlab.ui.control.Label
        SESSSIONSTATUSDescLabel       matlab.ui.control.Label
        SESSIONDRIFTDescLabel         matlab.ui.control.Label
        SESSIONSAMPLESDescLabel       matlab.ui.control.Label
        SESSIONDRIFTLabel             matlab.ui.control.Label
        SESSIONSAMPLESLabel           matlab.ui.control.Label
        SESSIONLENGTHLabel            matlab.ui.control.Label
        SESSIONLENGTHSDescLabel       matlab.ui.control.Label
        SESSIONENDEDLabel             matlab.ui.control.Label
        SESSIONENDEDDescLabel         matlab.ui.control.Label
        SESSIONSTARTEDLabel           matlab.ui.control.Label
        SESSIONSTARTEDDescLabel       matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        SETTINGSPanel                 matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        RoleLabel                     matlab.ui.control.Label
        RoleDropDown                  matlab.ui.control.DropDown
        DESELECTButton                matlab.ui.control.Button
        SESSIONLENGTHSEditField       matlab.ui.control.NumericEditField
        SESSIONLENGTHSEditFieldLabel  matlab.ui.control.Label
        CHANNELSLabel_3               matlab.ui.control.Label
        CHANNELSButton                matlab.ui.control.Button
        BADCHANNELSLabel              matlab.ui.control.Label
        PROTOCOLDropDown              matlab.ui.control.DropDown
        PROTOCOLLabel                 matlab.ui.control.Label
        WINDOWSIZESEditField          matlab.ui.control.NumericEditField
        WINDOWSIZESEditFieldLabel     matlab.ui.control.Label
        LSLSTREAMPanel                matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        SAMPLERATELabel               matlab.ui.control.Label
        SAMPLERATEDescLabel           matlab.ui.control.Label
        CHANNELSFOUNDLabel            matlab.ui.control.Label
        CHANNELSLabel_2               matlab.ui.control.Label
        OPENButton                    matlab.ui.control.Button
        TYPEEditField                 matlab.ui.control.EditField
        TYPELabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        tick uint64 = tic();
        isUnblinded logical = false;   % The application starts blinded
    end
    
    properties (Constant)
        idxred   uint32 = 6;
        idxgreen uint32 = 7;
        idxblue  uint32 = 8;
    end
    
    methods (Access = private)
        function onSessionStarted(app, src, ~)
            app.STARTButton.Text = "STOP";
            app.LoadMenu.Enable = false;
            app.SaveMenu.Enable = false;
            app.WINDOWSIZESEditField.Enable = false;
            app.SESSIONLENGTHSEditField.Enable = false;
            app.PROTOCOLDropDown.Enable = false;         
            app.CHANNELSButton.Enable = false;
            app.DESELECTButton.Enable = false;
            app.CONDITIONDropDown.Enable = false;
            app.SUBJECTEditField.Enable = false;
            app.RUNEditField.Enable = false;
            app.STUDYEditField.Enable = false;          
            app.OPENButton.Enable = false;
            app.MARKERTable.Enable = 'off';
            app.MARKERAddButton.Enable = false;
            app.MARKERDelButton.Enable = false;
            app.COLORButton.Enable = false;
            app.SESSIONSTARTEDLabel.Text = string(...
                datetime(src.starttime,'ConvertFrom','datenum'));
            app.SESSIONENDEDLabel.Text = "-";
            app.SESSIONSTATUSLabel.Text = "Started";
            app.applyBlindingUI();
            app.RANDOMIZEButton.Enable = false;
            app.updateRandomizeButtonState();
        end
        
        function onSessionStopped(app, src, ~)
            app.STARTButton.Text = "START";
            app.LoadMenu.Enable = true;
            app.SaveMenu.Enable = true;
            app.WINDOWSIZESEditField.Enable = true;
            app.SESSIONLENGTHSEditField.Enable = true;
            app.PROTOCOLDropDown.Enable = true;
            app.CHANNELSButton.Enable = true;
            app.SUBJECTEditField.Enable = true;
            app.RUNEditField.Enable = true;
            app.STUDYEditField.Enable = true;
            app.OPENButton.Enable = true;
            app.DESELECTButton.Enable = true;
            app.CONDITIONDropDown.Enable = true;  % it may get disabled again by useDevice() if randomized
            app.MARKERTable.Enable = 'on';
            app.MARKERAddButton.Enable = true;
            if size(app.MARKERTable.Data,1) > 0
                app.MARKERDelButton.Enable = true;
                app.COLORButton.Enable = true;
            end
            app.SESSIONENDEDLabel.Text = string(...
                datetime(src.stoptime,'ConvertFrom','datenum'));
            app.SESSIONSTATUSLabel.Text = "Stopped";
            app.updateStatus();
            app.applyBlindingUI();
            app.updateRandomizeButtonState();
        end

        function updateBlindMenus(app)
            % Keep menu enablement symmetric so you can only click
            % the action that changes state.
            if app.isUnblinded
                app.UnblindMenu.Enable = 'off';   % already unblinded
                app.BlindMenu.Enable   = 'on';    % allow re-blinding
            else
                app.UnblindMenu.Enable = 'on';    % allow unblinding
                app.BlindMenu.Enable   = 'off';   % already blinded
            end
        end


        function onChannelsSelected(app, ~, ~)
            app.updateStartButton();
        end

         function r = ifelse(~, c, a, b)
             if c, r = a; else, r = b; end
         end

        function r = tern(~, c, a, b)
            if c, r = a; else, r = b; end
        end

        function updateRandomizeButtonState(app)
            D  = app.MARKERTable.Data;
            ok = false;
        
            % Need at least 3 rows total (1 baseline + ≥2 eligible) and a TRANSFER column
            if ~isempty(D) && size(D,1) >= 3
                if istable(D)
                    v = string(D.Properties.VariableNames);
                    tfCol = find(startsWith(v,"TRAN",'IgnoreCase',true),1);
                    if ~isempty(tfCol)
                        elig = 2:size(D,1);
                        ok = nnz(logical(D{elig, tfCol})) >= 2;
                    end
                else
                    if size(D,2) >= 4
                        elig = 2:size(D,1);
                        ok = nnz(D(elig,4) ~= 0) >= 2;
                    end
                end
            end
        
            % Lock out during a running session
            try
                global mysession
                if ~isempty(mysession) && isvalid(mysession) && mysession.running
                    ok = false;
                end
            catch
            end
            % Check if the stream is open
            global mylsl
            lsl_status = mylsl.streaming;
            if lsl_status
                app.RANDOMIZEButton.Enable = matlab.lang.OnOffSwitchState(ok);
            end
        end

    end
    
    methods (Access = public)
        function updateColors(app)
            removeStyle(app.MARKERTable);
            numrows = size(app.MARKERTable.Data,1);
            for i = 1:numrows
                r = app.MARKERTable.Data(i, app.idxred);
                g = app.MARKERTable.Data(i, app.idxgreen);
                b = app.MARKERTable.Data(i, app.idxblue);
                s = uistyle();
                s.BackgroundColor = [r g b];
                s.FontColor = [r g b];
                addStyle(app.MARKERTable, s, 'cell', [i app.idxred]);
                addStyle(app.MARKERTable, s, 'cell', [i app.idxgreen]);
                addStyle(app.MARKERTable, s, 'cell', [i app.idxblue]);
            end
        end
        
        function updateStatus(app)
            global mysession
            expected = double(mysession.length) * mysession.srate;
            missing = expected - double(mysession.idx);
            drift = missing / mysession.srate;
            app.SESSIONLENGTHLabel.Text = ...
                sprintf('%.2f', mysession.length) + " s";
            app.SESSIONSAMPLESLabel.Text = ...
                string(mysession.idx) + "/" + ...
                string(mysession.datasize);
            app.SESSIONDRIFTLabel.Text = ...
                sprintf('%.2f', drift) + " s";
            if drift > 1.0 || drift < -1.0
                app.SESSIONDRIFTLabel.BackgroundColor = 'red';
            else
                app.SESSIONDRIFTLabel.BackgroundColor = 'green';
            end          
            app.PROTOCOLMaxLabel.Text = ...
                sprintf('%.2f', mysession.protocolmax) + " s";
            app.PROTOCOLAvgLabel.Text = ...
                sprintf('%.2f', mysession.protocolavg) + " s";           
            if mysession.protocolavg > (0.9*(1.0/mysession.srate))
                app.PROTOCOLAvgLabel.BackgroundColor = 'red';
            else
                app.PROTOCOLAvgLabel.BackgroundColor = 'green';
            end
        end

        function update(app)
            global mylsl
            global mysession
            if mylsl.streaming
                if toc(app.tick) >= 0.5
                    app.SAMPLERATELabel.Text = ...
                        sprintf('%.2f', mylsl.srate) + " Hz / " + ...
                        sprintf('%.2f', mylsl.sratenom) + " Hz";                
                    if mylsl.sratenom > 0.0
                        ratio = mylsl.srate/mylsl.sratenom;
                        if ratio < 0.9 || ratio > 1.1
                            app.SAMPLERATELabel.BackgroundColor = 'red';
                        else
                            app.SAMPLERATELabel.BackgroundColor = 'green';
                        end              
                    end
                    app.tick = tic();
                end
            end
            try
                if ~isempty(mysession) && isvalid(mysession) && mysession.running
                    app.updateStatus();
                end
            catch
                % ignore
            end
        end
        
        function updateStartButton(app)
            global myselectchannels
            global mylsl
            global mysession
            try
            if ~isempty(mysession) && isvalid(mysession) && mysession.running
                app.STARTButton.Text = "STOP";
                app.STARTButton.Enable = true;   % A clickable button to stop
                return;
            end
        catch
            % ignore
        end


            if ~mylsl.streaming
                app.STARTButton.Text = "CONNECT LSL FIRST";
                app.STARTButton.Enable = false;
                return;
            end
        
            if app.isUnblinded
                % If Sham is selected, require SS channels
                wantSham = false;
                try
                    wantSham = strcmpi(app.RoleDropDown.Value,'Sham');
                catch
                end
            
                if wantSham
                    hasSS = false;
                    try
                        hasSS = isprop(myselectchannels,'SSselected') && ~isempty(myselectchannels.SSselected);
                    catch
                        hasSS = false;
                    end
            
                    if ~hasSS
                        app.STARTButton.Text = "SELECT SHORT-SEP CHANNELS (SHAM)";
                        app.STARTButton.Enable = false;
                        return;
                    end
                end
            
                % Otherwise require general channel selection
                okSel = false;
                try
                    % if isok is a METHOD, call it: myselectchannels.isok()
                    okSel = myselectchannels.isok;
                catch
                    try
                        okSel = myselectchannels.isok();
                    catch
                        okSel = false;
                    end
                end
            
                if ~okSel
                    app.STARTButton.Text = "SELECT CHANNELS FIRST";
                    app.STARTButton.Enable = false;
                    return;
                end
            end
        
            app.STARTButton.Text = "START";
            app.STARTButton.Enable = true;
        end
        
        function updateDevices(app)
            global mydevices;
            app.DEVICEDropDown.Items = {};
            type = lower(app.TYPEDropDown.Value);
            for idx = 1:length(mydevices.(type))
                app.DEVICEDropDown.Items(idx) = ...
                    cellstr(mydevices.(type)(idx).name);
            end
        end
        
        function useDevice(app)
            global mydevices;
            global myprotocols;
        
            type = convertCharsToStrings(app.TYPEDropDown.Value);
            name = convertCharsToStrings(app.DEVICEDropDown.Value);
        
            if mydevices.select(type, name)
                dev = mydevices.selected;
        
                % Sanity check right after selecting
                if ~mydevices.sanityCheckSelected()
                    uialert(app.UIFigure, "Invalid device configuration: " + dev.name, "Error");
                    return;
                end
        
                disp("SELECTED DEVICE: " + dev.name + " (" + dev.type + ")");
        
                % Assign LSL type to UI
                if isfield(dev,'lsl') && isfield(dev.lsl,'type') && ~isempty(dev.lsl.type)
                    app.TYPEEditField.Value = dev.lsl.type;
                else
                    app.TYPEEditField.Value = "";
                end
        
                % ---------- CONDITION (A/B) DROPDOWN ----------
                % Build human labels (fallback to "Condition A/B")
                labelA = "Condition A";
                labelB = "Condition B";
                if isfield(dev,'modes')
                    if isfield(dev.modes,'A') && isfield(dev.modes.A,'label') && ~isempty(dev.modes.A.label)
                        labelA = string(dev.modes.A.label);
                    end
                    if isfield(dev.modes,'B') && isfield(dev.modes.B,'label') && ~isempty(dev.modes.B.label)
                        labelB = string(dev.modes.B.label);
                    end
                end
        
                % Populate items
                app.CONDITIONDropDown.Items = {char(labelA), char(labelB)};
        
                % Default selection follows device.default_mode (fallback A)
                defMode = "A";
                if isfield(dev,'default_mode') && ~isempty(dev.default_mode)
                    defMode = string(dev.default_mode);
                end
                if defMode == "A"
                    app.CONDITIONDropDown.Value = char(labelA);
                else
                    app.CONDITIONDropDown.Value = char(labelB);
                end
        
                % Store mapping so we can map display text <-> "A"/"B" later
                app.CONDITIONDropDown.UserData = struct('A', string(labelA), 'B', string(labelB));
        
                % Enable only if NOT randomized (randomize==false)
                isRand = (isfield(dev,'randomize') && logical(dev.randomize));
                app.CONDITIONDropDown.Enable = matlab.lang.OnOffSwitchState(~isRand);
                if ~isRand
                    app.CONDITIONDropDown.Tooltip = {'Choose condition (A/B) for this session.'};
                else
                    app.CONDITIONDropDown.Tooltip = {'Randomized at session start; manual choice disabled.'};
                end
                % ------------------------------------------------
        
                % Reload protocols for this device and repopulate dropdown
                myprotocols.reload(dev);
                app.PROTOCOLDropDown.Items = {};
                for idx = 1:length(myprotocols.list)
                    app.PROTOCOLDropDown.Items(idx) = cellstr(myprotocols.list(idx).name);
                end
        
                % Apply current protocol selection (rebuild required/selected channels)
                app.useProtocol();
                app.applyBlindingUI();
            end
        end


        function useProtocol(app)
            global myprotocols;
            global myselectchannels;

            name = convertCharsToStrings(app.PROTOCOLDropDown.Value);
            if myprotocols.select(name)
                disp("SELECTED PROTOCOL: " + myprotocols.selected.name)
        
                % Clear any old NF/SS selections:
                myselectchannels.selected   = [];
                myselectchannels.SSselected = [];
        
                % Rebuild both tables (Required & Selected) in one shot:
                myselectchannels.refreshAll();
        
                app.checkWindowSize();
                app.updateStartButton();
            end
        end
        
        function checkWindowSize(app)
            global myprotocols
            v = app.WINDOWSIZESEditField.Value;
            if ~isempty(myprotocols.selected)
                r = myprotocols.selected.fh.requires();
                v = min(max(v, r.window.mins), r.window.maxs);
            end
            if app.WINDOWSIZESEditField.Value ~= v
                app.WINDOWSIZESEditField.Value = v;
            end
        end

        function applyBlindingUI(app)
            global mylsl mysession
            % Single source of truth for blindness gating.
            blinded = ~app.isUnblinded;
        
            % External state
            isStreaming = false; isRunning = false;

             try
                isStreaming = ~isempty(mylsl) && isvalid(mylsl) && mylsl.streaming;
                isRunning   = ~isempty(mysession) && isvalid(mysession) && mysession.running;
            catch
                % ignore
            end

            % OPEN is locked during a run, free otherwise
            app.OPENButton.Enable = matlab.lang.OnOffSwitchState(~isRunning);
        
            % Configurable only if: unblinded, streaming, not running
            canConfigure = ~blinded && isStreaming && ~isRunning;
            app.PROTOCOLDropDown.Enable = matlab.lang.OnOffSwitchState(canConfigure);
            app.CHANNELSButton.Enable   = matlab.lang.OnOffSwitchState(canConfigure);

            % DESELECT should be allowed in BOTH modes when streaming & not running
            canDeselect = isStreaming && ~isRunning;
            app.DESELECTButton.Enable   = matlab.lang.OnOffSwitchState(canDeselect);
            app.DESELECTButton.Tooltip  = {'Remove bad channels (works in blinded & unblinded).'};
        
            % Role controls follow the same rule
            app.RoleDropDown.Enable     = matlab.lang.OnOffSwitchState(canConfigure);
            app.RoleLabel.Enable        = matlab.lang.OnOffSwitchState(canConfigure);
            app.RoleDropDown.Tooltip    = {'Choose Real or Sham (unblinded only).'};

        
            % Condition (A/B) should be available while blinded,
            % but if the device is randomized, the existing useDevice() logic
            % will disable it
        
           % Window title hint
            baseName = "NINFA v1.4.0";
            if blinded
                badge = " — Blinded";
                uiStateText = "blinded";
            else
                badge = " — Unblinded";
                uiStateText = "unblinded";
                app.CONDITIONDropDown.Enable = 'off';
                app.CONDITIONDropDown.Tooltip = {'Disabled in unblinded mode.'};
            end
            app.UIFigure.Name = baseName + badge;
            app.SESSIONSTATUSLabel.Tooltip = {char("UI is " + uiStateText)};

            % Re-check START state with the new gating
            if ~isRunning
                app.updateStartButton();
            else
                app.STARTButton.Text = "STOP";
                app.STARTButton.Enable = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global mysession;
            global myselectchannels;
            addlistener(mysession, "Started", @app.onSessionStarted);
            addlistener(mysession, "Stopped", @app.onSessionStopped);
            addlistener(myselectchannels, "Done", @app.onChannelsSelected);
            for idx = 1:length(devices.types)
                app.TYPEDropDown.Items(idx) = cellstr(devices.types(idx));
            end
            app.updateDevices();
            app.useDevice();
            app.updateRandomizeButtonState();
            app.applyBlindingUI();
            app.updateBlindMenus();
            app.MARKERTable.SelectionType = 'row';
            app.MARKERTable.ColumnFormat  = {'short','short','short','logical','logical','short','short','short'};
        end

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            global mylsl
            global myselectchannels
            global mydevices
            if ~mylsl.streaming
                r = mylsl.open(app.TYPEEditField.Value);
                if r
                    N_lsl = double(mylsl.N);
                    N_json = double(mydevices.selected.channels_per_block);

                    % Compare JSON vs LSL N
                    if N_lsl > 0 && N_json > 0 && N_lsl ~= N_json
                        mylsl.close();
                        msgbox("Channel mismatch: device N=" + string(mydevices.selected.channels_per_block) + ...
                               " but LSL N=" + string(mylsl.N), ...
                               "Error", "error");
                        app.updateStartButton();
                        return;
                    end
                    app.tick = tic();
                    app.OPENButton.Text = "CLOSE";
                    app.TYPEDropDown.Enable = false;
                    app.DEVICEDropDown.Enable = false;
                    app.TYPEEditField.Enable = false;
                    app.CHANNELSFOUNDLabel.Text = int2str(mylsl.lslchannels);
                    app.WINDOWSIZESEditField.Enable = true;
                    app.SESSIONLENGTHSEditField.Enable = true;
                    app.SUBJECTEditField.Enable = true;
                    app.RUNEditField.Enable = true;
                    app.STUDYEditField.Enable = true;
                    app.DESELECTButton.Enable = true;
                    app.MARKERTable.Enable = 'on';
                    app.MARKERAddButton.Enable = true;
                    app.updateRandomizeButtonState();
                    app.applyBlindingUI();
                    if size(app.MARKERTable.Data,1) > 0
                        app.MARKERDelButton.Enable = true;
                        app.COLORButton.Enable = true;
                    end
                else
                    msgbox("No LSL stream with type '" + ...
                        app.TYPEEditField.Value + ...
                        "' found", "Error", "error");
                end
            else
                mylsl.close();
                myselectchannels.close();
                app.OPENButton.Text = "OPEN";
                app.CHANNELSFOUNDLabel.Text = "-";
                app.SAMPLERATELabel.Text = "-";
                app.TYPEDropDown.Enable = true;
                app.DEVICEDropDown.Enable = true;
                app.TYPEEditField.Enable = true;
                app.WINDOWSIZESEditField.Enable = false;
                app.SESSIONLENGTHSEditField.Enable = false;
                app.PROTOCOLDropDown.Enable = false;
                app.SUBJECTEditField.Enable = false;
                app.RUNEditField.Enable = false;
                app.STUDYEditField.Enable = false;
                app.CHANNELSButton.Enable = false;
                app.DESELECTButton.Enable = false;
                app.MARKERTable.Enable = 'off';
                app.MARKERAddButton.Enable = false;
                app.MARKERDelButton.Enable = false;
                app.COLORButton.Enable = false;
                app.SAMPLERATELabel.BackgroundColor = 'none';
                app.updateRandomizeButtonState();
            end
            app.updateStartButton();
            app.applyBlindingUI();
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % Globals used by the app
            global mylsl;
            global mysession;
            global myselectchannels;
            global mydevices;
            global myprotocols;
        
            % Toggle start/stop
            if mysession.running
                mysession.stop();
                return;
            end
        
            % ----- START path -----
            device = mydevices.selected;
        
            % Decide whether we are in blinded flow (device enforces blind_role) or unblinded UI flow
            useBlindedFlow = isfield(device,'ui') && isfield(device.ui,'blind_role') ...
                             && logical(device.ui.blind_role) && ~app.isUnblinded;
        
            % Common default
            mysession.sham_uses_short = false;
        
            % Will be set in both flows
            protoName  = "";
            channels   = [];
            SSchannels = [];
        
            if useBlindedFlow
                % =========================
                % BLINDED FLOW -> JSON Based
                % =========================
        
                % If manual condition allowed (not randomized), read A/B label chosen by user
                manualPick = "";
                if strcmp(app.CONDITIONDropDown.Enable,'on')
                    ud     = app.CONDITIONDropDown.UserData;   % struct('A',labelA,'B',labelB)
                    picked = string(app.CONDITIONDropDown.Value);
                    if isfield(ud,'A') && picked == ud.A, manualPick = "A"; end
                    if isfield(ud,'B') && picked == ud.B, manualPick = "B"; end
                end
        
                % Determine assignment (manual > randomize > default)
                if manualPick ~= ""
                    A = assign_mode(device, 'manual', manualPick);
                else
                    A = assign_mode(device);
                end
        
                % Normalize protocol name (strip path/.m)
                protoName = string(A.protocol);
                [~, protoName] = fileparts(char(protoName));
        
                % Bind protocol; stop if missing
                if ~myprotocols.select(char(protoName))
                    uialert(app.UIFigure, "Protocol not found: " + protoName, "Error");
                    return;
                end
        
                % Channels from device JSON (long → NF, short → SS)
                try
                    channels   = mydevices.getLongChannelIndices();
                    SSchannels = mydevices.getShortChannelIndices();
                catch ME
                    uialert(app.UIFigure, "Channel mapping error: " + string(ME.message) + newline + ...
                                          "Check device/LSL.", "Error");
                    return;
                end
                if isempty(channels)
                    uialert(app.UIFigure, "No long channels found in JSON channel_map. Check device/LSL.", "Error");
                    return;
                end
        
                % If sham, drive with *short* channels; disable SS regression
                if strcmpi(A.role, 'sham')
                    if isempty(SSchannels)
                        uialert(app.UIFigure, "Sham condition requires short-separation channels, but none were found.", "Error");
                        return;
                    end
                    channels   = SSchannels;   % NF input becomes shorts
                    SSchannels = [];           % no SS reg (avoid self-regression)
                    mysession.sham_uses_short = true;
                end
        
                % --- Consistent session metadata ---
                mysession.mode_label        = A.label;          % "A"/"B"
                mysession.mode_role         = A.role;           % 'real' | 'sham'
                mysession.mode_protocol     = protoName;        % e.g., "MovAvg_SS"
                mysession.mode_source       = A.source;         % 'manual' | 'randomize' | 'default'
                mysession.mode_reason       = "";
                mysession.randomize_state   = (A.source == "randomize");
                mysession.default_mode_used = "";
                if isfield(device,'default_mode') && ~isempty(device.default_mode)
                    mysession.default_mode_used = string(device.default_mode);
                end
                mysession.randseed = A.seed;                    % [] unless randomized
                if isfield(device,'name'); mysession.json_filename = string(device.name); end
        
            else
                % =========================
                % UNBLINDED (MANUAL) FLOW
                % =========================
        
                % Manual channel selection is required in unblinded mode
                channels   = myselectchannels.selected;
                SSchannels = myselectchannels.SSselected;
        
                % Basic safety: must have channels unless sham (which requires SS)
                % (updateStartButton already enforces this, but we guard again)
                roleVal = "Real";
                try
                    if isprop(app,'RoleDropDown') && strcmp(app.RoleDropDown.Visible,'on')
                        roleVal = string(app.RoleDropDown.Value);   % 'Real' or 'Sham'
                    end
                catch
                    % if RoleDropDown is absent, default 'Real'
                end
        
                if strcmpi(roleVal,'Sham')
                    if isempty(SSchannels)
                        uialert(app.UIFigure, "Sham requires short-separation channels to be selected.", "Error");
                        return;
                    end
                    channels   = SSchannels;
                    SSchannels = [];
                    mysession.sham_uses_short = true;
                else
                    if isempty(channels)
                        uialert(app.UIFigure, "Please select channels before starting.", "Error");
                        return;
                    end
                end
        
                % Protocol from UI (psychologist choice)
                protoName = erase(app.PROTOCOLDropDown.Value, ".m");
                if ~myprotocols.select(char(protoName))
                    uialert(app.UIFigure, "Protocol not found: " + string(protoName), "Error");
                    return;
                end
        
                % Consistent session metadata for manual flow
                mysession.mode_label        = "";                   % no A/B label in manual flow
                mysession.mode_role         = lower(roleVal);       % 'real' | 'sham'
                mysession.mode_protocol     = string(protoName);
                mysession.mode_source       = "manual";
                mysession.mode_reason       = "";
                mysession.randomize_state   = false;
                mysession.default_mode_used = "";
                mysession.randseed          = [];
                if isfield(device,'name'); mysession.json_filename = string(device.name); end
            end
        
            % Common start sequence
        
            % Sampling rate & block size
            srate = mylsl.sratenom; 
            if srate <= 0, srate = mylsl.srate; end
            blocksize = app.WINDOWSIZESEditField.Value * srate;
        
            % Clamp channels to live stream range (safety)
            maxCh = mylsl.lslchannels;
            channels   = intersect(uint32(channels),   uint32(1:maxCh), 'stable');
            SSchannels = intersect(uint32(SSchannels), uint32(1:maxCh), 'stable');

            mysession.nf_channels_used = uint32(channels);
            mysession.ss_channels_used = uint32(SSchannels);
            
            % Reset stream with selected channels
            mylsl.reset(blocksize, channels, SSchannels);
        
            % Start session
            mysession.start( ...
                protoName, ...
                app.SESSIONLENGTHSEditField.Value, ...
                app.WINDOWSIZESEditField.Value, ...
                srate, ...
                device, ...
                channels, ...
                SSchannels, ...
                app.MARKERTable.Data, ...
                app.STUDYEditField.Value, ...
                app.SUBJECTEditField.Value, ...
                app.RUNEditField.Value);


        end

        % Button pushed function: MARKERAddButton
        function MARKERAddButtonPushed(app, event)
            app.MARKERTable.Data = [app.MARKERTable.Data; [0 0 1 0 1 0 0 0]];
            app.MARKERTable.Selection = size(app.MARKERTable.Data,1);
            app.MARKERDelButton.Enable = true;
            app.COLORButton.Enable = true;
            app.updateColors();
            app.updateRandomizeButtonState();  
        end

        % Button pushed function: MARKERDelButton
        function MARKERDelButtonPushed(app, event)
            if ~isempty(app.MARKERTable.Selection)                
                numrows = size(app.MARKERTable.Data,1);
                app.MARKERTable.Data(app.MARKERTable.Selection,:) = [];
                if app.MARKERTable.Selection >= numrows
                    if numrows > 1; app.MARKERTable.Selection = numrows-1;
                    else; app.MARKERTable.Selection = [];
                    end
                end
                numrows = size(app.MARKERTable.Data,1);
                if numrows <= 0
                    app.MARKERDelButton.Enable = false;
                    app.COLORButton.Enable = false;
                end
                app.updateColors();
                app.updateRandomizeButtonState();
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            % Menu selected function: LoadMenu
            global mylsl;
            global myselectchannels;
        
            [file, path] = uigetfile("./settings/*.mat");
            figure(app.UIFigure); % focus back
            if isequal(file,0) || isequal(path,0)
                return;
            end
            filepath = string(path) + string(file);
        
            % Load settings struct
            settings = load(filepath);
        
            % DEVICE / TYPE
            if isfield(settings, 'devicetype')
                app.TYPEDropDown.Value = settings.devicetype;
                app.updateDevices();
            end
            if isfield(settings, 'devicename')
                if any(strcmp(app.DEVICEDropDown.Items, settings.devicename))
                    app.DEVICEDropDown.Value = settings.devicename;
                    app.useDevice();
                else
                    msgbox("Device '" + settings.devicename + ...
                        "' was not found on this computer", "Warning", "warn");
                end
            end
            if isfield(settings, 'lsltype')
                app.TYPEEditField.Value = settings.lsltype;
            end
        
            % CHANNELS / WINDOW / LENGTH
            if isfield(settings, 'channels')
                myselectchannels.selected = settings.channels;
            end
            if isfield(settings, 'windowsize')
                app.WINDOWSIZESEditField.Value = settings.windowsize;
            end
            if isfield(settings, 'sessionlength')
                app.SESSIONLENGTHSEditField.Value = settings.sessionlength;
            end
        
            % PROTOCOL
            if isfield(settings, 'protocol')
                if any(strcmp(app.PROTOCOLDropDown.Items, settings.protocol))
                    app.PROTOCOLDropDown.Value = settings.protocol;
                    app.useProtocol();
                else
                    msgbox("Protocol '" + settings.protocol + ...
                        "' was not found on this computer", "Warning", "warn");
                end
            end
        
            % EPOCHS (with schema upgrade & baseline safeguard) 
            if isfield(settings, 'epochs')
                E = settings.epochs;
        
                % Old schema (7 cols): [START END MARKER VISIBLE R G B]
                % New schema (8 cols): [START END MARKER TRANSFER VISIBLE R G B]
                if istable(E)
                    % Table: ensure a TRANSFER-like column exists; if missing, add it as false
                    v = string(E.Properties.VariableNames);
                    tfCol = find(startsWith(v,"TRAN",'IgnoreCase',true),1);
                    if isempty(tfCol)
                        % Insert TRANSFER column as logical false after MARKER (3rd col)
                        E = addvars(E, false(height(E),1), 'After', 3, 'NewVariableNames', 'TRANSFER');
                        tfCol = find(startsWith(string(E.Properties.VariableNames),"TRAN",'IgnoreCase',true),1);
                    end
                    % Also ensure VISIBLE/R/G/B exist (best-effort if user brings older tables)
                    v = string(E.Properties.VariableNames);
                    if ~any(strcmpi(v,'VISIBLE')), E = addvars(E, true(height(E),1), 'After', tfCol, 'NewVariableNames','VISIBLE'); end
                    v = string(E.Properties.VariableNames);
                    needRGB = ~all(ismember({'R','G','B'}, v));
                    if needRGB
                        E = addvars(E, zeros(height(E),1), zeros(height(E),1), zeros(height(E),1), ...
                            'After', 'VISIBLE', 'NewVariableNames', {'R','G','B'});
                    end
        
                    % Force baseline TRANSFER=false
                    if height(E) >= 1 && ~isempty(tfCol)
                        if islogical(E{1,tfCol}), E{1,tfCol} = false;
                        else,                    E{1,tfCol} = cast(0,'like',E{1,tfCol});
                        end
                    end
        
                else
                    % Numeric matrix: pad to 8 columns
                    if size(E,2) == 7
                        % Old: [S E MARKER VISIBLE R G B] -> insert TRANSFER=0 at col 4
                        vis = E(:,4); rgb = E(:,5:7);
                        E  = [E(:,1:3), zeros(size(E,1),1), vis, rgb];
                    elseif size(E,2) < 8
                        E(:, end+1:8) = 0;
                    end
        
                    % Force baseline TRANSFER=false (col 4)
                    if ~isempty(E)
                        E(1,4) = 0;
                    end
                end
        
                % Push to UI
                app.MARKERTable.Data = E;
        
                % Enable related buttons if streaming & table nonempty
                if ~isempty(app.MARKERTable.Data) && mylsl.streaming
                    app.MARKERDelButton.Enable = true;
                    app.COLORButton.Enable     = true;
                end
                app.updateColors();
                app.updateRandomizeButtonState();
            end
        
            % STUDY
            if isfield(settings, 'study')
                app.STUDYEditField.Value = settings.study;
            end

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            global mydevices;
            global myselectchannels;
            [file, path] = uiputfile("./settings/*.mat");
            figure(app.UIFigure); % focus back
            if isequal(file,0) || isequal(path,0)
                return;
            end
            filepath = string(path) + string(file);
            settings.devicetype = mydevices.selected.type;
            settings.devicename = mydevices.selected.name;
            settings.lsltype = app.TYPEEditField.Value;
            settings.channels = myselectchannels.selected;
            settings.windowsize = app.WINDOWSIZESEditField.Value;
            settings.sessionlength = app.SESSIONLENGTHSEditField.Value;
            settings.protocol = app.PROTOCOLDropDown.Value;
            settings.epochs = app.MARKERTable.Data;
            settings.study = app.STUDYEditField.Value;
            settings.role = app.RoleDropDown.Value;
            save(filepath, '-struct','settings');
        end

        % Cell edit callback: MARKERTable
        function MARKERTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            row = indices(1,1);
            col = indices(1,2);
            if col == 3
                if newData < 1
                    app.MARKERTable.Data(row,col) = 1;
                elseif newData > 99
                    app.MARKERTable.Data(row,col) = 99;
                end
            elseif col == 1 || col == 2
                if newData < 0
                    app.MARKERTable.Data(row,col) = 0;
                end
            end
             if col == 4      % TRANSFER column toggled
                app.updateRandomizeButtonState();
            end
        end

        % Button pushed function: COLORButton
        function COLORButtonPushed(app, event)
            c = uisetcolor([0 0 0]);
            rows = app.MARKERTable.Selection;         
            for r = rows
                app.MARKERTable.Data(r, app.idxred)   = c(1);
                app.MARKERTable.Data(r, app.idxgreen) = c(2);
                app.MARKERTable.Data(r, app.idxblue)  = c(3);
            end           
            app.updateColors();
        end

        % Value changed function: DEVICEDropDown
        function DEVICEDropDownValueChanged(app, event)
            app.useDevice();
        end

        % Value changed function: TYPEDropDown
        function TYPEDropDownValueChanged(app, event)
            app.updateDevices();
            app.useDevice();
        end

        % Button pushed function: CHANNELSButton
        function CHANNELSButtonPushed(app, event)
            global myselectchannels;
            myselectchannels.show();
        end

        % Value changed function: PROTOCOLDropDown
        function PROTOCOLDropDownValueChanged(app, event)
            app.useProtocol();
        end

        % Value changed function: WINDOWSIZESEditField
        function WINDOWSIZESEditFieldValueChanged(app, event)
            app.checkWindowSize();
        end

        % Button pushed function: DESELECTButton
        function DESELECTButtonPushed(app, event)

             % Globals we need
            global myselectchannels;
            sc = myselectchannels;  % shorthand
        
            if isempty(sc) || ~isvalid(sc)
                uialert(app.UIFigure, 'Channel-selection module not available.', 'Error');
                return;
            end
        
            % --- BLINDING STATE ---
            % Blinded: hide “Type/LSL/Remove NF/Remove SS” columns, but STILL allow
            % choosing which underlying signal set to operate on (HbO/HbR/Both).
            isBlinded = ~app.isUnblinded;
        
            % ---------- DIALOG SHELL ----------
            dlg = uifigure( ...
                'Name','Deselect by Device Channels', ...
                'Position',[100 100 560 380], ...
                'Resize','off', ...
                'WindowStyle','modal');
        
            g = uigridlayout(dlg,[5 1]);
            g.Padding   = [10 10 10 10];
            g.RowHeight = {40, 40, '1x', 24, 40};
        
            % ---------- Row 1: Remove type selector (always visible) ----------
            row1 = uigridlayout(g,[1 4]); 
            row1.Layout.Row  = 1;
            row1.ColumnWidth = {95, 85, 85, '1x'};
        
            % Label wording: neutralized when blinded
            if isBlinded
                uilabel(row1,'Text','Remove:','HorizontalAlignment','right');
            else
                uilabel(row1,'Text','Remove type:','HorizontalAlignment','right');
            end
        
            bg = uibuttongroup(row1,'BorderType','none'); 
            bg.Layout.Column = [2 4];
        
            % Button labels: neutral in blinded mode (but map internally)
            if isBlinded
                t1 = 'Type 1'; t2 = 'Type 2'; tb = 'Both';
            else
                t1 = 'HbO';    t2 = 'HbR';    tb = 'Both';
            end
            r1  = uiradiobutton(bg,'Text',t1);  r1.Position  = [  5 5 60 22];
            r2  = uiradiobutton(bg,'Text',t2);  r2.Position  = [ 70 5 60 22];
            rb  = uiradiobutton(bg,'Text',tb);  rb.Position  = [135 5 60 22];
        
            % remember last choice across opens
            persistent lastChoice; if isempty(lastChoice), lastChoice = t1; end
            switch lastChoice
                case t2,  bg.SelectedObject = r2;
                case tb,  bg.SelectedObject = rb;
                otherwise, bg.SelectedObject = r1;
            end
        
            % ---------- Row 2: Input + helper ----------
            row2 = uigridlayout(g,[2 2]);
            row2.Layout.Row   = 2;
            row2.RowHeight    = {24, 16};
            row2.ColumnWidth  = {230, '1x'};
            uilabel(row2,'Text','Device channels (comma-separated):','HorizontalAlignment','right');
            txt = uieditfield(row2,'text','Placeholder','e.g., 3, 7, 12,'); 
            txt.Layout.Column = 2;
            helper = uilabel(row2,'Text','Spaces/trailing commas are ok. Only numbers are accepted.', ...
                             'FontColor',[.4 .4 .4]);
            helper.Layout.Row = 2; helper.Layout.Column = [1 2];
        
            % ---------- Row 3: Preview table ----------
            row3 = uigridlayout(g,[1 1]); 
            row3.Layout.Row = 3;
        
            if isBlinded
                % Redacted in blinded mode
                tbl = uitable(row3,'Data',cell(0,2), ...
                    'ColumnName',{'Devch','Remove'}, ...
                    'ColumnEditable',[false false], ...
                    'ColumnWidth',{60 80});
            else
                % Full detail when unblinded
                tbl = uitable(row3,'Data',cell(0,5), ...
                    'ColumnName',{'Devch','Type','LSL','Remove NF','Remove SS'}, ...
                    'ColumnEditable',[false false false false false], ...
                    'ColumnWidth',{60 50 60 80 80});
            end
        
            % ---------- Row 4: Ignored tokens ----------
            ignoredLbl = uilabel(g,'Text',' ');
            ignoredLbl.Layout.Row = 4;
            ignoredLbl.FontColor  = [.6 .2 .2];
            ignoredLbl.FontAngle  = 'italic';
        
            % ---------- Row 5: Buttons ----------
            row5 = uigridlayout(g,[1 3]); 
            row5.Layout.Row   = 5; 
            row5.ColumnWidth  = {'1x','1x','1x'};
            btnPrev   = uibutton(row5,'Text','Preview','FontWeight','bold');
            btnApply  = uibutton(row5,'Text','Apply','FontWeight','bold','Enable','off');
            btnCancel = uibutton(row5,'Text','Cancel');
        
            % ---------- HELPERS ----------
            % Map the UI radio choice → underlying sets (HbO/HbR/Both)
            function mode = currentMode()
                sel = string(bg.SelectedObject.Text);
                if sel == t1, mode = "HbO";
                elseif sel == t2, mode = "HbR";
                else, mode = "Both";
                end
            end
        
            % Parse entry -> two lists (HbO, HbR) based on currentMode
            function [dHbO, dHbR, devchs, ignored] = getLists()
                P = sc.parseDevchInput(txt.Value);     % tolerant parse
                ignored = P.ignored;
                devchs  = double(P.devchs);
                switch currentMode()
                    case "HbO",  dHbO = devchs; dHbR = [];
                    case "HbR",  dHbO = [];     dHbR = devchs;
                    otherwise,   dHbO = devchs; dHbR = devchs;
                end
            end
        
            function S = doPreview()
                % Parse & show ignored tokens
                [dHbO, dHbR, devchs, ignored] = getLists();
                if ~isempty(ignored)
                    ignoredLbl.Text = "Ignored tokens: " + strjoin(cellstr(ignored), ', ');
                else
                    ignoredLbl.Text = " ";
                end
        
                % Simulate deselection once
                S = sc.simulateDeselect(uint32(dHbO), uint32(dHbR));
        
                if isBlinded
                    % ------- Blinded table (Devch | Remove) -------
                    rows = {};
                    invalid = [];
                    L = unique([S.hbo.nf_remove, S.hbo.ss_remove, S.hbr.nf_remove, S.hbr.ss_remove]);
                    willAnyRemove = false;
                    for k = 1:numel(devchs)
                        d = devchs(k);
                        will = false; foundAny = false;
                        % We don’t reveal type, but we test the active mode’s mapping(s)
                        switch currentMode()
                            case "HbO"
                                try l = sc.devch_to_lsl(d,'HbO'); foundAny = true; will = will || ismember(l,L); catch, end
                            case "HbR"
                                try l = sc.devch_to_lsl(d,'HbR'); foundAny = true; will = will || ismember(l,L); catch, end
                            otherwise
                                try l = sc.devch_to_lsl(d,'HbO'); foundAny = true; will = will || ismember(l,L); catch, end
                                try l = sc.devch_to_lsl(d,'HbR'); foundAny = true; will = will || ismember(l,L); catch, end
                        end
                        if ~foundAny, invalid(end+1) = d; end %#ok<AGROW>
                        rows(end+1, :) = {d, logical(will)}; %#ok<AGROW>
                        willAnyRemove = willAnyRemove || will;
                    end
                    tbl.Data = rows;
        
                    if ~isempty(invalid)
                        btnApply.Enable = 'off';
                        uialert(dlg, "Some device channel(s) were not recognized. Please check the list.", ...
                                'Not Found', 'Icon','error');
                    else
                        btnApply.Enable = matlab.lang.OnOffSwitchState(willAnyRemove);
                        if ~willAnyRemove && ~isempty(devchs)
                            uialert(dlg, 'No removable mappings found for these channels.', ...
                                    'Nothing To Remove', 'Icon','info');
                        end
                    end
        
                else
                    % ------- Unblinded table (full detail) -------
                    rows = {};
                    invalidPairs = strings(0,1);
        
                    nfRemove = unique([S.hbo.nf_remove, S.hbr.nf_remove]);
                    ssRemove = unique([S.hbo.ss_remove, S.hbr.ss_remove]);
        
                    % Expand to explicit pairs according to currentMode
                    devchsAll = []; tAll = strings(0,1);
                    if any(strcmp(currentMode(),["HbO","Both"]))
                        devchsAll = [devchsAll; dHbO(:)]; tAll = [tAll; repmat("HbO",numel(dHbO),1)];
                    end
                    if any(strcmp(currentMode(),["HbR","Both"]))
                        devchsAll = [devchsAll; dHbR(:)]; tAll = [tAll; repmat("HbR",numel(dHbR),1)];
                    end
        
                    for k = 1:numel(devchsAll)
                        d = devchsAll(k);  t = tAll(k);
                        lslStr = '-'; remNF = false; remSS = false;
                        try
                            lslIdx = sc.devch_to_lsl(d, t);
                            lslStr = num2str(lslIdx);
                            remNF  = ismember(lslIdx, nfRemove);
                            remSS  = ismember(lslIdx, ssRemove);
                        catch
                            invalidPairs(end+1) = t + "(" + string(d) + ")"; %#ok<AGROW>
                        end
                        rows(end+1, :) = {d, char(t), lslStr, logical(remNF), logical(remSS)}; %#ok<AGROW>
                    end
                    tbl.Data = rows;
        
                    if ~isempty(invalidPairs)
                        btnApply.Enable = 'off';
                        uialert(dlg, "Unknown device channel(s): " + strjoin(cellstr(invalidPairs), ', '), ...
                                'Not Found', 'Icon','error');
                    else
                        willRemove = (S.totals.nf_remove + S.totals.ss_remove) > 0;
                        btnApply.Enable = matlab.lang.OnOffSwitchState(willRemove);
                    end
                end
            end
        
            % ---------- APPLY ----------
            function onApply()
                [dHbO, dHbR, devchs] = getLists();
        
                % Remember last radio choice
                lastChoice = bg.SelectedObject.Text; %#ok<NASGU>
        
                % Try apply; show redacted error if blinded
                try
                    R = sc.applyDeselect(uint32(dHbO), uint32(dHbR));
                catch ME
                    if isBlinded
                        uialert(dlg, 'Some device channel(s) could not be matched. Please check your list.', ...
                                'Not Found', 'Icon','error');
                    else
                        uialert(dlg, ME.message, 'Not Found', 'Icon','error');
                    end
                    return;
                end
        
                app.updateStartButton();   % reflect in main UI
                close(dlg);
        
                % Confirmation message
                if isBlinded
                    if isempty(devchs)
                        chTxt = "nothing";
                    else
                        chTxt = strjoin(string(devchs), ',');
                    end
                    total_before = R.nf_before + R.ss_before;
                    total_after  = R.nf_after  + R.ss_after;
                    uialert(app.UIFigure, ...
                        sprintf('Removed %s. Total channels %d→%d.', chTxt, total_before, total_after), ...
                        'Deselection Complete');
                else
                    parts = string([]);
                    if ~isempty(dHbO), parts(end+1) = "HbO(" + strjoin(string(dHbO),',') + ")"; end
                    if ~isempty(dHbR), parts(end+1) = "HbR(" + strjoin(string(dHbR),',') + ")"; end
                    if isempty(parts), parts = "nothing"; end
                    uialert(app.UIFigure, ...
                        sprintf('Removed %s. NF %d→%d, SS %d→%d.', ...
                            strjoin(cellstr(parts), '; '), R.nf_before, R.nf_after, R.ss_before, R.ss_after), ...
                        'Deselection Complete');
                end
            end
        
            % ---------- Wire callbacks ----------
            btnPrev.ButtonPushedFcn   = @(~,~) doPreview();
            btnApply.ButtonPushedFcn  = @(~,~) onApply();
            btnCancel.ButtonPushedFcn = @(~,~) close(dlg);
            bg.SelectionChangedFcn    = @(~,~) doPreview();
            txt.ValueChangedFcn       = @(~,~) doPreview();
            dlg.KeyPressFcn           = @(~,e) onKey(e);
        
            function onKey(e)
                if strcmp(e.Key,'return') && ismember('control',e.Modifier)
                    if strcmp(btnApply.Enable,'on'), onApply(); end
                elseif strcmp(e.Key,'return')
                    doPreview();
                elseif strcmp(e.Key,'escape')
                    close(dlg);
                end
            end
        
            % First preview on open
            doPreview();
        end

        % Menu selected function: UnblindMenu
        function UnblindMenuSelected(app, event)
            % Ask before switching to UNBLINDED mode
            selection = uiconfirm(app.UIFigure, ...
                "Unblind now? Manual protocol & channel selection will be enabled.", ...
                "Confirm Unblind", ...
                'Options', {'Cancel','Unblind'}, ...
                'DefaultOption', 2, ...
                'Icon', 'warning');
            if ~strcmp(selection, 'Unblind'); return; end
        
            % Safety: do not flip mid-run
            try
                global mysession
                if ~isempty(mysession) && isvalid(mysession) && mysession.running
                    uialert(app.UIFigure, ...
                        'Stop the session before switching to unblinded mode.', ...
                        'Session Running', 'Icon', 'warning');
                    return;
                end
            catch
                % ignore lookup errors
            end
        
            % Flip state → UNBLINDED
            app.isUnblinded = true;
        
            % Update menus/UI
            app.updateBlindMenus();
            app.applyBlindingUI();
            app.updateStartButton();
        
            % Post-action confirmation
            uialert(app.UIFigure, ...
                'Unblinded: you can now select Protocol and Channels.', ...
                'Unblinded');
        end

        % Value changed function: CONDITIONDropDown
        function CONDITIONDropDownValueChanged(app, event)
            value = app.CONDITIONDropDown.Value;
            
        end

        % Menu selected function: BlindMenu
        function BlindMenuSelected(app, event)
            % Ask before switching to BLINDED mode
            selection = uiconfirm(app.UIFigure, ...
                "Blind now? Manual protocol & channel selection will be disabled.", ...
                "Confirm Blind", ...
                'Options', {'Cancel','Blind'}, ...
                'DefaultOption', 2, ...
                'Icon', 'warning');
            if ~strcmp(selection, 'Blind'); return; end
        
            % Safety: do not flip mid-run
            try
                global mysession
                if ~isempty(mysession) && isvalid(mysession) && mysession.running
                    uialert(app.UIFigure, ...
                        'Stop the session before switching to blinded mode.', ...
                        'Session Running', 'Icon', 'warning');
                    return;
                end
            catch
                % ignore lookup errors
            end
        
            % Flip state → BLINDED
            app.isUnblinded = false;
        
            % Update menus/UI
            app.updateBlindMenus();
            app.applyBlindingUI();
            app.updateStartButton();
        
            % Post-action confirmation
            uialert(app.UIFigure, ...
                'Blinded: protocol & channel selection is now disabled.', ...
                'Blinded');
        
        end

        % Button pushed function: RANDOMIZEButton
        function RANDOMIZEButtonPushed(app, event)
            D = app.MARKERTable.Data;
            if isempty(D) || size(D,1) < 3     % need at least 2 eligible rows (rows 2..end)
                return
            end
        
            if istable(D)
                % Find TRANSFER column (accepts 'TRANS...')
                v = string(D.Properties.VariableNames);
                tfCol = find(startsWith(v,"TRAN",'IgnoreCase',true),1);
                if isempty(tfCol), return; end
        
                % Force baseline row to false
                if islogical(D{1,tfCol}), D{1,tfCol} = false; else, D{1,tfCol} = cast(0,'like',D{1,tfCol}); end
        
                elig = (2:size(D,1)).';                 % rows eligible for randomization
                cur  = logical(D{elig, tfCol});         % current flags among eligible rows
                k    = nnz(cur);                        % keep the same number of TRUEs
        
                rng('shuffle');
                perm = elig(randperm(numel(elig)));     % shuffle which rows get TRUE
                newFlags = false(size(D,1),1);
                newFlags(perm(1:k)) = true;             % assign k TRUEs to random rows
                % Write back only elig rows; leave all other columns untouched
                if islogical(D{elig, tfCol})
                    D{elig, tfCol} = newFlags(elig);
                else
                    D{elig, tfCol} = cast(newFlags(elig),'like',D{elig, tfCol});
                end
        
            else
                % Numeric matrix schema: [START END MARKER TRANSFER VISIBLE R G B]
                if size(D,2) < 4, return; end
                tfCol = 4;
        
                % Force baseline row to false
                D(1,tfCol) = 0;
        
                elig = (2:size(D,1)).';
                cur  = D(elig, tfCol) ~= 0;
                k    = nnz(cur);
        
                rng('shuffle');
                perm = elig(randperm(numel(elig)));
                newFlags = false(size(D,1),1);
                newFlags(perm(1:k)) = true;
                D(elig, tfCol) = double(newFlags(elig));
            end
        
            app.MARKERTable.Data = D;
            app.updateColors();                % colors unchanged but keep UI consistent
            app.updateRandomizeButtonState();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.UnblindMenu = uimenu(app.FileMenu);
            app.UnblindMenu.MenuSelectedFcn = createCallbackFcn(app, @UnblindMenuSelected, true);
            app.UnblindMenu.Text = 'Unblind';
            app.BlindMenu = uimenu(app.FileMenu);
            app.BlindMenu.MenuSelectedFcn = createCallbackFcn(app, @BlindMenuSelected, true);
            app.BlindMenu.Enable = 'off';
            app.BlindMenu.Text = 'Blind';
            app.LSLSTREAMPanel = uipanel(app.UIFigure);
            app.LSLSTREAMPanel.Title = 'LSL STREAM';
            app.GridLayout = uigridlayout(app.LSLSTREAMPanel);
            app.TYPELabel = uilabel(app.GridLayout);
            app.TYPELabel.Text = 'TYPE';
            app.TYPEEditField = uieditfield(app.GridLayout, 'text');
            app.OPENButton = uibutton(app.GridLayout, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.CHANNELSLabel_2 = uilabel(app.GridLayout);
            app.CHANNELSLabel_2.Text = 'CHANNELS';
            app.CHANNELSFOUNDLabel = uilabel(app.GridLayout);
            app.CHANNELSFOUNDLabel.Text = '-';
            app.SAMPLERATEDescLabel = uilabel(app.GridLayout);
            app.SAMPLERATEDescLabel.Text = 'SAMPLE RATE';
            app.SAMPLERATELabel = uilabel(app.GridLayout);
            app.SAMPLERATELabel.Text = '-';
            app.SETTINGSPanel = uipanel(app.UIFigure);
            app.SETTINGSPanel.Title = 'SETTINGS';
            app.GridLayout2 = uigridlayout(app.SETTINGSPanel);
            app.WINDOWSIZESEditFieldLabel = uilabel(app.GridLayout2);
            app.WINDOWSIZESEditFieldLabel.Text = 'WINDOW SIZE (S)';
            app.WINDOWSIZESEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WINDOWSIZESEditField.Limits = [0 3600];
            app.WINDOWSIZESEditField.ValueChangedFcn = createCallbackFcn(app, @WINDOWSIZESEditFieldValueChanged, true);
            app.WINDOWSIZESEditField.Enable = 'off';
            app.WINDOWSIZESEditField.Value = 1;
            app.PROTOCOLLabel = uilabel(app.GridLayout2);
            app.PROTOCOLLabel.Text = 'PROTOCOL';
            app.PROTOCOLDropDown = uidropdown(app.GridLayout2);
            app.PROTOCOLDropDown.Items = {};
            app.PROTOCOLDropDown.ValueChangedFcn = createCallbackFcn(app, @PROTOCOLDropDownValueChanged, true);
            app.PROTOCOLDropDown.Enable = 'off';
            app.PROTOCOLDropDown.Tooltip = {'Select the Matlab file that should be executed on each window calculating the next feedback. '};
            app.PROTOCOLDropDown.Value = {};
            app.BADCHANNELSLabel = uilabel(app.GridLayout2);
            app.BADCHANNELSLabel.Text = 'BAD CHANNELS';
            app.CHANNELSButton = uibutton(app.GridLayout2, 'push');
            app.CHANNELSButton.ButtonPushedFcn = createCallbackFcn(app, @CHANNELSButtonPushed, true);
            app.CHANNELSButton.Enable = 'off';
            app.CHANNELSButton.Text = 'SELECT';
            app.CHANNELSLabel_3 = uilabel(app.GridLayout2);
            app.CHANNELSLabel_3.Text = 'CHANNELS';
            app.SESSIONLENGTHSEditFieldLabel = uilabel(app.GridLayout2);
            app.SESSIONLENGTHSEditFieldLabel.Text = 'SESSION LENGTH (S)';
            app.SESSIONLENGTHSEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SESSIONLENGTHSEditField.Limits = [0 3600];
            app.SESSIONLENGTHSEditField.Enable = 'off';
            app.SESSIONLENGTHSEditField.Value = 10;
            app.DESELECTButton = uibutton(app.GridLayout2, 'push');
            app.DESELECTButton.ButtonPushedFcn = createCallbackFcn(app, @DESELECTButtonPushed, true);
            app.DESELECTButton.Enable = 'off';
            app.DESELECTButton.Text = 'DESELECT';
            app.RoleDropDown = uidropdown(app.GridLayout2);
            app.RoleDropDown.Items = {'Real', 'Sham'};
            app.RoleDropDown.Enable = 'off';
            app.RoleDropDown.Value = 'Real';
            app.RoleLabel = uilabel(app.GridLayout2);
            app.RoleLabel.Text = 'Role';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Tooltip = {'Start or stop session'};
            app.STARTButton.Text = 'START';
            app.SESSIONINFOPanel = uipanel(app.UIFigure);
            app.SESSIONINFOPanel.Title = 'SESSION INFO';
            app.GridLayout3 = uigridlayout(app.SESSIONINFOPanel);
            app.SESSIONSTARTEDDescLabel = uilabel(app.GridLayout3);
            app.SESSIONSTARTEDDescLabel.Text = 'STARTED:';
            app.SESSIONSTARTEDLabel = uilabel(app.GridLayout3);
            app.SESSIONSTARTEDLabel.Text = '-';
            app.SESSIONENDEDDescLabel = uilabel(app.GridLayout3);
            app.SESSIONENDEDDescLabel.Text = 'ENDED:';
            app.SESSIONENDEDLabel = uilabel(app.GridLayout3);
            app.SESSIONENDEDLabel.Text = '-';
            app.SESSIONLENGTHSDescLabel = uilabel(app.GridLayout3);
            app.SESSIONLENGTHSDescLabel.Text = 'DURATION:';
            app.SESSIONLENGTHLabel = uilabel(app.GridLayout3);
            app.SESSIONLENGTHLabel.Text = '-';
            app.SESSIONSAMPLESLabel = uilabel(app.GridLayout3);
            app.SESSIONSAMPLESLabel.Text = '-';
            app.SESSIONDRIFTLabel = uilabel(app.GridLayout3);
            app.SESSIONDRIFTLabel.Text = '-';
            app.SESSIONSAMPLESDescLabel = uilabel(app.GridLayout3);
            app.SESSIONSAMPLESDescLabel.Text = 'SAMPLES:';
            app.SESSIONDRIFTDescLabel = uilabel(app.GridLayout3);
            app.SESSIONDRIFTDescLabel.Text = 'DELAY';
            app.SESSSIONSTATUSDescLabel = uilabel(app.GridLayout3);
            app.SESSSIONSTATUSDescLabel.Text = 'STATUS:';
            app.SESSIONSTATUSLabel = uilabel(app.GridLayout3);
            app.SESSIONSTATUSLabel.Text = 'Stopped';
            app.EPOCHSPanel = uipanel(app.UIFigure);
            app.EPOCHSPanel.Title = 'EPOCHS';
            app.MARKERTable = uitable(app.EPOCHSPanel);
            app.MARKERTable.ColumnName = {'START (S)'; 'END (S)'; 'MARKER'; 'TRANSFER'; 'VISIBLE'; 'R'; 'G'; 'B'};
            app.MARKERTable.RowName = {};
            app.MARKERTable.ColumnEditable = [true true true true true false false false];
            app.MARKERTable.CellEditCallback = createCallbackFcn(app, @MARKERTableCellEdit, true);
            app.MARKERTable.Tooltip = {'Define markers (a value between 1 and 99) for epochs here. An epoch is defined by its start and end time. A trigger will be sent at the beginning of each epoch.'};
            app.MARKERTable.Enable = 'off';
            app.MARKERAddButton = uibutton(app.EPOCHSPanel, 'push');
            app.MARKERAddButton.ButtonPushedFcn = createCallbackFcn(app, @MARKERAddButtonPushed, true);
            app.MARKERAddButton.Enable = 'off';
            app.MARKERAddButton.Tooltip = {'Add an epoch'};
            app.MARKERAddButton.Text = '+';
            app.MARKERDelButton = uibutton(app.EPOCHSPanel, 'push');
            app.MARKERDelButton.ButtonPushedFcn = createCallbackFcn(app, @MARKERDelButtonPushed, true);
            app.MARKERDelButton.Enable = 'off';
            app.MARKERDelButton.Tooltip = {'Remove last or selected epochs'};
            app.MARKERDelButton.Text = '-';
            app.COLORButton = uibutton(app.EPOCHSPanel, 'push');
            app.COLORButton.ButtonPushedFcn = createCallbackFcn(app, @COLORButtonPushed, true);
            app.COLORButton.Enable = 'off';
            app.COLORButton.Tooltip = {'Select color for selected epochs'};
            app.COLORButton.Text = 'COLOR';
            app.RANDOMIZEButton = uibutton(app.EPOCHSPanel, 'push');
            app.RANDOMIZEButton.ButtonPushedFcn = createCallbackFcn(app, @RANDOMIZEButtonPushed, true);
            app.RANDOMIZEButton.Enable = 'off';
            app.RANDOMIZEButton.Text = 'RANDOMIZE';
            app.IDPanel = uipanel(app.UIFigure);
            app.IDPanel.Title = 'ID';
            app.GridLayout4 = uigridlayout(app.IDPanel);
            app.SUBJECTEditFieldLabel = uilabel(app.GridLayout4);
            app.SUBJECTEditFieldLabel.Text = 'SUBJECT';
            app.SUBJECTEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SUBJECTEditField.Limits = [1 10000];
            app.SUBJECTEditField.Enable = 'off';
            app.SUBJECTEditField.Value = 1;
            app.RUNEditFieldLabel = uilabel(app.GridLayout4);
            app.RUNEditFieldLabel.Text = 'RUN';
            app.RUNEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RUNEditField.Limits = [1 1000];
            app.RUNEditField.Enable = 'off';
            app.RUNEditField.Value = 1;
            app.STUDYEditFieldLabel = uilabel(app.GridLayout4);
            app.STUDYEditFieldLabel.Text = 'STUDY';
            app.STUDYEditField = uieditfield(app.GridLayout4, 'text');
            app.STUDYEditField.Enable = 'off';
            app.STUDYEditField.Tooltip = {''};
            app.PROTOCOLTIMEPanel = uipanel(app.UIFigure);
            app.PROTOCOLTIMEPanel.Title = 'PROTOCOL TIME';
            app.GridLayout5 = uigridlayout(app.PROTOCOLTIMEPanel);
            app.PROTOCOLMaxLabel = uilabel(app.GridLayout5);
            app.PROTOCOLMaxLabel.Text = '-';
            app.PROTOCOLMaxDescLabel = uilabel(app.GridLayout5);
            app.PROTOCOLMaxDescLabel.Text = 'MAX:';
            app.PROTOCOLAvgLabel = uilabel(app.GridLayout5);
            app.PROTOCOLAvgLabel.Text = '-';
            app.PROTOCOLAvgDescLabel = uilabel(app.GridLayout5);
            app.PROTOCOLAvgDescLabel.Text = 'AVG:';
            app.DEVICEPanel = uipanel(app.UIFigure);
            app.DEVICEPanel.Title = 'DEVICE';
            app.GridLayout6 = uigridlayout(app.DEVICEPanel);
            app.MODELDropDownLabel = uilabel(app.GridLayout6);
            app.MODELDropDownLabel.Text = 'MODEL';
            app.DEVICEDropDown = uidropdown(app.GridLayout6);
            app.DEVICEDropDown.Items = {};
            app.DEVICEDropDown.ValueChangedFcn = createCallbackFcn(app, @DEVICEDropDownValueChanged, true);
            app.DEVICEDropDown.Value = {};
            app.TYPEDropDownLabel = uilabel(app.GridLayout6);
            app.TYPEDropDownLabel.Text = 'TYPE';
            app.TYPEDropDown = uidropdown(app.GridLayout6);
            app.TYPEDropDown.Items = {};
            app.TYPEDropDown.ValueChangedFcn = createCallbackFcn(app, @TYPEDropDownValueChanged, true);
            app.TYPEDropDown.Value = {};
            app.CONDITIONLabel = uilabel(app.GridLayout6);
            app.CONDITIONLabel.Text = 'CONDITION';
            app.CONDITIONDropDown = uidropdown(app.GridLayout6);
            app.CONDITIONDropDown.Items = {};
            app.CONDITIONDropDown.ValueChangedFcn = createCallbackFcn(app, @CONDITIONDropDownValueChanged, true);
            app.CONDITIONDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end